classdef LC2RC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TextArea                    matlab.ui.control.TextArea
        SaveButton                  matlab.ui.control.Button
        DataProcessingOptionsLabel  matlab.ui.control.Label
        Tree                        matlab.ui.container.CheckBoxTree
        ChannelsincludedNode        matlab.ui.container.TreeNode
        Channel1Node                matlab.ui.container.TreeNode
        Channel2Node                matlab.ui.container.TreeNode
        Channel3Node                matlab.ui.container.TreeNode
        Channel4Node                matlab.ui.container.TreeNode
        Channel5Node                matlab.ui.container.TreeNode
        Channel6Node                matlab.ui.container.TreeNode
        ProcessButton               matlab.ui.control.Button
        FilePathEditField           matlab.ui.control.EditField
        FilePathEditFieldLabel      matlab.ui.control.Label
        PlotR2R1RatioCheckBox       matlab.ui.control.CheckBox
        PlotforMuscleSpineCheckBox  matlab.ui.control.CheckBox
        NormalizeCheckBox           matlab.ui.control.CheckBox
        SavePlotasCSVCheckBox       matlab.ui.control.CheckBox
        IncludeR2CheckBox           matlab.ui.control.CheckBox
        DelayEditField              matlab.ui.control.NumericEditField
        DelayEditFieldLabel         matlab.ui.control.Label
        WindowEditField             matlab.ui.control.NumericEditField
        WindowEditFieldLabel        matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Phase = 1 % 1: window adjustment, 2 = data plotting 
        %Makesure WindowEditField is default 100 
        
       
    end
       
    
    methods (Access = private)


        function plotRC(app)
            %Insert the plotting function here, pltot to the axes 


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % When pushed, the button will plot depending on the phase the app is
            % in

            window_size = app.WindowEditField.Value;
            delay = app.DelayEditField; 


            switch app.Phase
                case 1 % window adjusting
                    %should plot the window, and the delay, overlaying a
                    %sample piece of data (maybe channel 3 at one of the
                    %later amplitudes 

                    %Calls the plot RC function, but modified s.t. it takes
                    %in 
               
                case 2 %do plot rc
                   
                otherwise disp('error phase is not 1 or 2, call the programmer to debug')
            end 

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            
            %DEMO: for now, run AutomationDraft
            AutomationDraft(app.FilePathEditField.Value);
            
            % Should Process the data
            % Should create nodes in the channels included tree
            % Should show raw comments to error check
            % Shou


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.WindowEditFieldLabel = uilabel(app.UIFigure);
            app.WindowEditFieldLabel.Text = 'Window';
            app.WindowEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowEditField.Value = 100;
            app.DelayEditFieldLabel = uilabel(app.UIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelayEditField.Value = 130;
            app.IncludeR2CheckBox = uicheckbox(app.UIFigure);
            app.IncludeR2CheckBox.Text = 'Include R2';
            app.IncludeR2CheckBox.Value = true;
            app.SavePlotasCSVCheckBox = uicheckbox(app.UIFigure);
            app.SavePlotasCSVCheckBox.Text = 'Save Plot as CSV';
            app.SavePlotasCSVCheckBox.Value = true;
            app.NormalizeCheckBox = uicheckbox(app.UIFigure);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.PlotforMuscleSpineCheckBox = uicheckbox(app.UIFigure);
            app.PlotforMuscleSpineCheckBox.Text = 'Plot for Muscle / Spine';
            app.PlotR2R1RatioCheckBox = uicheckbox(app.UIFigure);
            app.PlotR2R1RatioCheckBox.Text = 'Plot R2/R1 Ratio ';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.Value = '20240408_RTA002_EPA3_RC ONLY TSS.adicht';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.ChannelsincludedNode = uitreenode(app.Tree);
            app.ChannelsincludedNode.Text = 'Channels included';
            app.Channel1Node = uitreenode(app.ChannelsincludedNode);
            app.Channel1Node.Text = 'Channel1';
            app.Channel2Node = uitreenode(app.ChannelsincludedNode);
            app.Channel2Node.Text = 'Channel2';
            app.Channel3Node = uitreenode(app.ChannelsincludedNode);
            app.Channel3Node.Text = 'Channel3';
            app.Channel4Node = uitreenode(app.ChannelsincludedNode);
            app.Channel4Node.Text = 'Channel4';
            app.Channel5Node = uitreenode(app.ChannelsincludedNode);
            app.Channel5Node.Text = 'Channel5';
            app.Channel6Node = uitreenode(app.ChannelsincludedNode);
            app.Channel6Node.Text = 'Channel6';
            app.DataProcessingOptionsLabel = uilabel(app.UIFigure);
            app.DataProcessingOptionsLabel.Text = 'Data Processing Options';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LC2RC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end